classdef deshify < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Setimlook4dwindowTab            matlab.ui.container.Tab
        HTML4                           matlab.ui.control.HTML
        Selectimlook4dwindowButton      matlab.ui.control.Button
        HTML2_2                         matlab.ui.control.HTML
        TopHatTab                       matlab.ui.container.Tab
        HTML3                           matlab.ui.control.HTML
        HTML2                           matlab.ui.control.HTML
        ShrinkButton                    matlab.ui.control.Button
        LateralVentricleTab             matlab.ui.container.Tab
        InflatewidthpixelsSpinner       matlab.ui.control.Spinner
        InflatewidthpixelsSpinnerLabel  matlab.ui.control.Label
        HTML2_3                         matlab.ui.control.HTML
        InflateButton                   matlab.ui.control.Button
        HTML_lat_vent_result            matlab.ui.control.HTML
        ThresholdRegionsTab             matlab.ui.container.Tab
        PerformThresholding             matlab.ui.control.Button
        CalcThresholdButton             matlab.ui.control.Button
        HTML2_6                         matlab.ui.control.HTML
        HTML2_5                         matlab.ui.control.HTML
        thresholdLevel                  matlab.ui.control.NumericEditField
        ThresholdlevelLabel             matlab.ui.control.Label
        HTML2_4                         matlab.ui.control.HTML
        HTML3_3                         matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShrinkButton
        function top_hat_shrink(app, event)
            evalin('base', 'ROI_data_to_workspace' );
            evalin('base', 'top_hat_shrink');
            if ( evalin('base', 'returnCode' ) == 0)
                app.HTML3.HTMLSource = 'SUCCESS, next tab';
            else
                app.HTML3.HTMLSource = 'FAIL, check MATLAB command window';
            end
            
        end

        % Button pushed function: InflateButton
        function inflate(app, event)
            evalin('base', 'ROI_data_to_workspace' );
            disp(['dr=' app.InflatewidthpixelsSpinner.Value ]);
            evalin('base', ['deshify_dr = ' num2str(app.InflatewidthpixelsSpinner.Value) ]); % Override dr in script
            evalin('base', 'inflate_VOI');
            if ( evalin('base', 'returnCode' ) == 0)
                app.HTML_lat_vent_result.HTMLSource = 'SUCCESS, next tab';
            else
                app.HTML_lat_vent_result.HTMLSource = 'FAIL, check MATLAB command window';
            end
                
        end

        % Button pushed function: Selectimlook4dwindowButton
        function selectImlook4dWindow(app, event)
            evalin('base', 'imlook4d_current_handle = gcf' );
            evalin('base', 'ROI_data_to_workspace' );
        end

        % Button pushed function: CalcThresholdButton
        function calcGlobalThreshold(app, event)
            % Merge VOIs (except for ROI 4 'Flood-fill block' )
            evalin('base', ...
                'deshify_data0 = [ imlook4d_ROI_data.pixels{ 1}; imlook4d_ROI_data.pixels{ 2}; imlook4d_ROI_data.pixels{ 3}; imlook4d_ROI_data.pixels{ 5} ]' );
            
            % Calculate threshold
            evalin('base', 'csf_threshold2_back' );
            app.thresholdLevel.Value = evalin('base', 'threshold');
        end

        % Button pushed function: PerformThresholding
        function performGlobalThresholding(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Setimlook4dwindowTab = uitab(app.TabGroup);
            app.Setimlook4dwindowTab.Title = '0) Set imlook4d window';
            app.HTML2_2 = uihtml(app.Setimlook4dwindowTab);
            app.HTML2_2.HTMLSource = '<b>Instruction : </b><br>1) Open a T1 MRI in ACPC space using imlook4d  <br>2) Draw the following VOIs according to instructions <br> <ol> <li> Sylvian sin</li> <li> Sylvian dx</li> <li> Top hat</li> <li>Flood-fill block</li> <li> Lat vent </li></ol>3) Make imlook4d window you wish to work on active<br>  4) Click "Select imlook4d window" ';
            app.Selectimlook4dwindowButton = uibutton(app.Setimlook4dwindowTab, 'push');
            app.Selectimlook4dwindowButton.ButtonPushedFcn = createCallbackFcn(app, @selectImlook4dWindow, true);
            app.Selectimlook4dwindowButton.Text = 'Select imlook4d window';
            app.HTML4 = uihtml(app.Setimlook4dwindowTab);
            app.HTML4.HTMLSource = '5) Switch to next tab';
            app.TopHatTab = uitab(app.TabGroup);
            app.TopHatTab.Title = '1) Top Hat';
            app.ShrinkButton = uibutton(app.TopHatTab, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @top_hat_shrink, true);
            app.ShrinkButton.Text = 'Shrink';
            app.HTML2 = uihtml(app.TopHatTab);
            app.HTML2.HTMLSource = '<b>Instruction : </b><br>1) Switch current VOI to "Top Hat"  <br>2) Press "Shrink" button';
            app.HTML3 = uihtml(app.TopHatTab);
            app.HTML3.HTMLSource = 'Wait for result ...';
            app.LateralVentricleTab = uitab(app.TabGroup);
            app.LateralVentricleTab.Title = '2) Lateral Ventricle';
            app.HTML_lat_vent_result = uihtml(app.LateralVentricleTab);
            app.HTML_lat_vent_result.HTMLSource = 'Wait for result ...';
            app.InflateButton = uibutton(app.LateralVentricleTab, 'push');
            app.InflateButton.ButtonPushedFcn = createCallbackFcn(app, @inflate, true);
            app.InflateButton.Text = 'Inflate';
            app.HTML2_3 = uihtml(app.LateralVentricleTab);
            app.HTML2_3.HTMLSource = '<b>Instruction : </b><br>1) Switch current VOI to "Lat Vent"  <br>2) Press "Inflate" button';
            app.InflatewidthpixelsSpinnerLabel = uilabel(app.LateralVentricleTab);
            app.InflatewidthpixelsSpinnerLabel.Text = 'Inflate width (pixels)';
            app.InflatewidthpixelsSpinner = uispinner(app.LateralVentricleTab);
            app.InflatewidthpixelsSpinner.Limits = [1 Inf];
            app.InflatewidthpixelsSpinner.Value = 7;
            app.ThresholdRegionsTab = uitab(app.TabGroup);
            app.ThresholdRegionsTab.Title = '3) Threshold Regions';
            app.HTML3_3 = uihtml(app.ThresholdRegionsTab);
            app.HTML3_3.HTMLSource = 'Wait for result ...';
            app.HTML2_4 = uihtml(app.ThresholdRegionsTab);
            app.HTML2_4.HTMLSource = '<b>Instruction : </b><br>1) Click "Calculate Global Threshold" button  ';
            app.ThresholdlevelLabel = uilabel(app.ThresholdRegionsTab);
            app.ThresholdlevelLabel.Text = 'Threshold level';
            app.thresholdLevel = uieditfield(app.ThresholdRegionsTab, 'numeric');
            app.HTML2_5 = uihtml(app.ThresholdRegionsTab);
            app.HTML2_5.HTMLSource = '3) Click "Perform Thresholding" button for all VOIs';
            app.HTML2_6 = uihtml(app.ThresholdRegionsTab);
            app.HTML2_6.HTMLSource = '2) Inspect results, and adjust level if needed ';
            app.CalcThresholdButton = uibutton(app.ThresholdRegionsTab, 'push');
            app.CalcThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @calcGlobalThreshold, true);
            app.CalcThresholdButton.Text = 'Calculate Global Threshold';
            app.PerformThresholding = uibutton(app.ThresholdRegionsTab, 'push');
            app.PerformThresholding.ButtonPushedFcn = createCallbackFcn(app, @performGlobalThresholding, true);
            app.PerformThresholding.Text = 'Perform Thresholding';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deshify

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end